classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AirshipControllerLabel       matlab.ui.control.Label
        Battery95Label               matlab.ui.control.Label
        Button_3                     matlab.ui.control.Button
        Pressure0005atmLabel         matlab.ui.control.Label
        CalibrateButton              matlab.ui.control.Button
        x289Label                    matlab.ui.control.Label
        y2376Label                   matlab.ui.control.Label
        Altitude25mLabel             matlab.ui.control.Label
        z362Label                    matlab.ui.control.Label
        Velocitymsx004y149z002Label  matlab.ui.control.Label
        DistanceMeasurementsLabel    matlab.ui.control.Label
        X200Label                    matlab.ui.control.Label
        X200Label_2                  matlab.ui.control.Label
        Y200Label                    matlab.ui.control.Label
        Y200Label_2                  matlab.ui.control.Label
        Z200Label                    matlab.ui.control.Label
        Z200Label_2                  matlab.ui.control.Label
        Accelerationms2x002y003z001Label  matlab.ui.control.Label
        xGaugeLabel                  matlab.ui.control.Label
        xGauge                       matlab.ui.control.SemicircularGauge
        GyroscopesLabel              matlab.ui.control.Label
        yGaugeLabel                  matlab.ui.control.Label
        yGauge                       matlab.ui.control.SemicircularGauge
        zGaugeLabel                  matlab.ui.control.Label
        zGauge                       matlab.ui.control.SemicircularGauge
        ServoOutputxyzLabel          matlab.ui.control.Label
        MotorOutputxyzLabel          matlab.ui.control.Label
        HeadingIndicator             Aero.ui.control.HeadingIndicator
        DestinationDropDownLabel     matlab.ui.control.Label
        DestinationDropDown          matlab.ui.control.DropDown
        GoButton                     matlab.ui.control.Button
        ErrormessageLabel            matlab.ui.control.Label
        SpeedmsSliderLabel           matlab.ui.control.Label
        SpeedmsSlider                matlab.ui.control.Slider
        RecallButton                 matlab.ui.control.Button
        CurrentLocationLabel         matlab.ui.control.Label
        CAGBLabel                    matlab.ui.control.Label
        WebcamButton                 matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        WarningButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                drawnow;
                app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: WebcamButton
        function WebcamButtonPushed(app, event)
            cam = webcam;
            frame = snapshot(cam);
            im = image(app.UIAxes, zeros(size(frame),'uint8'));
            axis(app.UIAxes, 'image');
            
            preview(cam,im);
            pause;
        end

        % Close request function: UIFigure
        function GoButtonPushed(app, event)
            %get value that user selected from the dropdown
            destination = app.DestinationDropDown.Value;
            
            %push to database
            URL = sprintf('https://airship-a31a9.firebaseio.com/GUIcommands/%s.json',destination);
            options = weboptions('KeyName', 'X-HTTP-Method-Override', 'KeyValue','PUT');
            webwrite(URL,'true',options);
        end

        % Value changing function: SpeedmsSlider
        function SpeedmsSliderValueChanging(app, event)
            %get value from slider
            speed = num2str(event.Value);
            
            %push to database
            URL = 'https://airship-a31a9.firebaseio.com/GUIspeed.json';
            options = weboptions('KeyName', 'X-HTTP-Method-Override', 'KeyValue','PUT');
            webwrite(URL,speed,options);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.AirshipControllerLabel = uilabel(app.UIFigure);
            app.AirshipControllerLabel.Text = 'Airship Controller';
            app.Battery95Label = uilabel(app.UIFigure);
            app.Battery95Label.Text = 'Battery: 95%';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Icon = 'observer_map2.png';
            app.Button_3.Text = '';
            app.Pressure0005atmLabel = uilabel(app.UIFigure);
            app.Pressure0005atmLabel.Text = 'Pressure: 0.005atm';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.Text = 'Calibrate';
            app.x289Label = uilabel(app.UIFigure);
            app.x289Label.Text = 'x: 2.89';
            app.y2376Label = uilabel(app.UIFigure);
            app.y2376Label.Text = 'y: 23.76';
            app.Altitude25mLabel = uilabel(app.UIFigure);
            app.Altitude25mLabel.Text = 'Altitude: 2.5m';
            app.z362Label = uilabel(app.UIFigure);
            app.z362Label.Text = 'z: 3.62';
            app.Velocitymsx004y149z002Label = uilabel(app.UIFigure);
            app.Velocitymsx004y149z002Label.Text = 'Velocity (m/s):          x: 0.04          y: 1.49          z: 0.02';
            app.DistanceMeasurementsLabel = uilabel(app.UIFigure);
            app.DistanceMeasurementsLabel.Text = 'Distance Measurements:';
            app.X200Label = uilabel(app.UIFigure);
            app.X200Label.Text = '+X: 2.00 ';
            app.X200Label_2 = uilabel(app.UIFigure);
            app.X200Label_2.Text = '-X: 2.00 ';
            app.Y200Label = uilabel(app.UIFigure);
            app.Y200Label.Text = '+Y: 2.00 ';
            app.Y200Label_2 = uilabel(app.UIFigure);
            app.Y200Label_2.Text = '-Y: 2.00';
            app.Z200Label = uilabel(app.UIFigure);
            app.Z200Label.Text = '+Z: 2.00';
            app.Z200Label_2 = uilabel(app.UIFigure);
            app.Z200Label_2.Text = '-Z: 2.00';
            app.Accelerationms2x002y003z001Label = uilabel(app.UIFigure);
            app.Accelerationms2x002y003z001Label.Text = 'Acceleration (m/s^2):          x: -0.02          y: 0.03          z: 0.01';
            app.xGaugeLabel = uilabel(app.UIFigure);
            app.xGaugeLabel.Text = 'x';
            app.xGauge = uigauge(app.UIFigure, 'semicircular');
            app.xGauge.Limits = [-15 15];
            app.GyroscopesLabel = uilabel(app.UIFigure);
            app.GyroscopesLabel.Text = 'Gyroscopes:';
            app.yGaugeLabel = uilabel(app.UIFigure);
            app.yGaugeLabel.Text = 'y';
            app.yGauge = uigauge(app.UIFigure, 'semicircular');
            app.yGauge.Limits = [-15 15];
            app.zGaugeLabel = uilabel(app.UIFigure);
            app.zGaugeLabel.Text = 'z';
            app.zGauge = uigauge(app.UIFigure, 'semicircular');
            app.zGauge.Limits = [-15 15];
            app.ServoOutputxyzLabel = uilabel(app.UIFigure);
            app.ServoOutputxyzLabel.Text = 'Servo Output (%):          x:           y:           z: ';
            app.MotorOutputxyzLabel = uilabel(app.UIFigure);
            app.MotorOutputxyzLabel.Text = 'Motor Output (%):          x:           y:           z: ';
            app.HeadingIndicator = uiaeroheading(app.UIFigure);
            app.DestinationDropDownLabel = uilabel(app.UIFigure);
            app.DestinationDropDownLabel.Text = 'Destination';
            app.DestinationDropDown = uidropdown(app.UIFigure);
            app.DestinationDropDown.Items = {'Option 1', 'CAGB', 'Option 3', 'Option 4'};
            app.DestinationDropDown.Value = 'CAGB';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.Text = 'Go!';
            app.ErrormessageLabel = uilabel(app.UIFigure);
            app.ErrormessageLabel.Text = 'Error message!';
            app.SpeedmsSliderLabel = uilabel(app.UIFigure);
            app.SpeedmsSliderLabel.Text = 'Speed (m/s)';
            app.SpeedmsSlider = uislider(app.UIFigure);
            app.SpeedmsSlider.Limits = [0.5 1.5];
            app.SpeedmsSlider.ValueChangingFcn = createCallbackFcn(app, @SpeedmsSliderValueChanging, true);
            app.SpeedmsSlider.Value = 1.5;
            app.RecallButton = uibutton(app.UIFigure, 'push');
            app.RecallButton.Text = 'Recall';
            app.CurrentLocationLabel = uilabel(app.UIFigure);
            app.CurrentLocationLabel.Text = 'Current Location:';
            app.CAGBLabel = uilabel(app.UIFigure);
            app.CAGBLabel.Text = 'CAGB';
            app.WebcamButton = uibutton(app.UIFigure, 'push');
            app.WebcamButton.ButtonPushedFcn = createCallbackFcn(app, @WebcamButtonPushed, true);
            app.WebcamButton.Text = 'Webcam';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.WarningButton = uibutton(app.UIFigure, 'push');
            app.WarningButton.Text = 'Warning!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end