classdef GUItest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AirshipControllerLabel       matlab.ui.control.Label
        DestinationDropDownLabel     matlab.ui.control.Label
        ToDropDown                   matlab.ui.control.DropDown
        GoButton                     matlab.ui.control.Button
        StatusLabel                  matlab.ui.control.Label
        SpeedmsSliderLabel           matlab.ui.control.Label
        SpeedSlider                  matlab.ui.control.Slider
        RecallButton                 matlab.ui.control.Button
        CurrentLocationLabel         matlab.ui.control.Label
        LocationLabel                matlab.ui.control.Label
        WebcamButton                 matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        WarnButton                   matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        PeopleCounter6Label          matlab.ui.control.Label
        SafetoproceedLabel           matlab.ui.control.Label
        SafeDropDown                 matlab.ui.control.DropDown
        SensorMeasurementsPanel      matlab.ui.container.Panel
        Battery95Label               matlab.ui.control.Label
        Pressure0005atmLabel         matlab.ui.control.Label
        CalibrateButton              matlab.ui.control.Button
        x289Label                    matlab.ui.control.Label
        y2376Label                   matlab.ui.control.Label
        Altitude25mLabel             matlab.ui.control.Label
        z362Label                    matlab.ui.control.Label
        Velocitymsx004y149z002Label  matlab.ui.control.Label
        DistanceMeasurementsLabel    matlab.ui.control.Label
        X200Label                    matlab.ui.control.Label
        X200Label_2                  matlab.ui.control.Label
        Y200Label                    matlab.ui.control.Label
        Y200Label_2                  matlab.ui.control.Label
        Z200Label                    matlab.ui.control.Label
        Z200Label_2                  matlab.ui.control.Label
        Accelerationms2x002y003z001Label  matlab.ui.control.Label
        GyroscopesLabel              matlab.ui.control.Label
        ServoOutputxyzLabel          matlab.ui.control.Label
        MotorOutputxyzLabel          matlab.ui.control.Label
        HeadingIndicator             Aero.ui.control.HeadingIndicator
        xGaugeLabel                  matlab.ui.control.Label
        xGauge                       matlab.ui.control.SemicircularGauge
        yGaugeLabel                  matlab.ui.control.Label
        yGauge                       matlab.ui.control.SemicircularGauge
        zGaugeLabel                  matlab.ui.control.Label
        zGauge                       matlab.ui.control.SemicircularGauge
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                drawnow;
                %app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: WebcamButton
        function WebcamButtonPushed(app, event)
            cam = webcam;
            frame = snapshot(cam);
            im = image(app.UIAxes, zeros(size(frame),'uint8'));
            axis(app.UIAxes, 'image');
            
            preview(cam,im);
            pause;
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            if app.SafeDropDown.Value == "Yes"
                app.StatusLabel.Text = "";
                [app.StatusLabel.Text,app.LocationLabel.Text] = runscript2(app.UIFigure,app.LocationLabel.Text,app.ToDropDown.Value,app.SpeedSlider.Value);
            else
                app.StatusLabel.Text = "It is not safe yet to leave the room"
                WarnButtonPushed(app, event)
            end
        end

        % Button pushed function: RecallButton
        function RecallButtonPushed(app, event)
            app.ToDropDown.Value = "CAGB";
            GoButtonPushed(app,event)
        end

        % Button pushed function: WarnButton
        function WarnButtonPushed(app, event)
            %emit warning siren from blimp
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AirshipControllerLabel = uilabel(app.UIFigure);
            app.AirshipControllerLabel.Text = 'Airship Controller';
            app.DestinationDropDownLabel = uilabel(app.UIFigure);
            app.DestinationDropDownLabel.Text = 'Destination';
            app.ToDropDown = uidropdown(app.UIFigure);
            app.ToDropDown.Items = {'SAF', 'Southside', 'Prince''s Garden', 'CAGB', 'Skempton', 'Queen''s Lawn', 'Library', 'Sherfield', 'Huxley', 'Blackett', 'Roderic Hill', 'RSM', 'Sports Centre', 'Beit Quad'};
            app.ToDropDown.Value = 'CAGB';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = '';
            app.SpeedmsSliderLabel = uilabel(app.UIFigure);
            app.SpeedmsSliderLabel.Text = 'Speed (m/s)';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Limits = [0.5 1.5];
            app.SpeedSlider.Value = 1.5;
            app.RecallButton = uibutton(app.UIFigure, 'push');
            app.RecallButton.ButtonPushedFcn = createCallbackFcn(app, @RecallButtonPushed, true);
            app.RecallButton.Text = 'Recall';
            app.CurrentLocationLabel = uilabel(app.UIFigure);
            app.CurrentLocationLabel.Text = 'Current Location:';
            app.LocationLabel = uilabel(app.UIFigure);
            app.LocationLabel.Text = 'CAGB';
            app.WebcamButton = uibutton(app.UIFigure, 'push');
            app.WebcamButton.ButtonPushedFcn = createCallbackFcn(app, @WebcamButtonPushed, true);
            app.WebcamButton.Text = 'Webcam';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.WarnButton = uibutton(app.UIFigure, 'push');
            app.WarnButton.ButtonPushedFcn = createCallbackFcn(app, @WarnButtonPushed, true);
            app.WarnButton.Text = 'Warn!';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.PeopleCounter6Label = uilabel(app.UIFigure);
            app.PeopleCounter6Label.Text = 'People Counter: 6';
            app.SafetoproceedLabel = uilabel(app.UIFigure);
            app.SafetoproceedLabel.Text = 'Safe to proceed? ';
            app.SafeDropDown = uidropdown(app.UIFigure);
            app.SafeDropDown.Items = {'Yes', 'No', 'Option 3', 'Option 4'};
            app.SafeDropDown.Value = 'Yes';
            app.SensorMeasurementsPanel = uipanel(app.UIFigure);
            app.SensorMeasurementsPanel.Title = 'Sensor Measurements';
            app.Battery95Label = uilabel(app.SensorMeasurementsPanel);
            app.Battery95Label.Text = 'Battery: 95%';
            app.Pressure0005atmLabel = uilabel(app.SensorMeasurementsPanel);
            app.Pressure0005atmLabel.Text = 'Pressure: 0.005atm';
            app.CalibrateButton = uibutton(app.SensorMeasurementsPanel, 'push');
            app.CalibrateButton.Text = 'Calibrate';
            app.x289Label = uilabel(app.SensorMeasurementsPanel);
            app.x289Label.Text = 'x: 2.89';
            app.y2376Label = uilabel(app.SensorMeasurementsPanel);
            app.y2376Label.Text = 'y: 23.76';
            app.Altitude25mLabel = uilabel(app.SensorMeasurementsPanel);
            app.Altitude25mLabel.Text = 'Altitude: 2.5m';
            app.z362Label = uilabel(app.SensorMeasurementsPanel);
            app.z362Label.Text = 'z: 3.62';
            app.Velocitymsx004y149z002Label = uilabel(app.SensorMeasurementsPanel);
            app.Velocitymsx004y149z002Label.Text = 'Velocity (m/s):          x: 0.04          y: 1.49          z: 0.02';
            app.DistanceMeasurementsLabel = uilabel(app.SensorMeasurementsPanel);
            app.DistanceMeasurementsLabel.Text = 'Distance Measurements:';
            app.X200Label = uilabel(app.SensorMeasurementsPanel);
            app.X200Label.Text = '+X: 2.00 ';
            app.X200Label_2 = uilabel(app.SensorMeasurementsPanel);
            app.X200Label_2.Text = '-X: 2.00 ';
            app.Y200Label = uilabel(app.SensorMeasurementsPanel);
            app.Y200Label.Text = '+Y: 2.00 ';
            app.Y200Label_2 = uilabel(app.SensorMeasurementsPanel);
            app.Y200Label_2.Text = '-Y: 2.00';
            app.Z200Label = uilabel(app.SensorMeasurementsPanel);
            app.Z200Label.Text = '+Z: 2.00';
            app.Z200Label_2 = uilabel(app.SensorMeasurementsPanel);
            app.Z200Label_2.Text = '-Z: 2.00';
            app.Accelerationms2x002y003z001Label = uilabel(app.SensorMeasurementsPanel);
            app.Accelerationms2x002y003z001Label.Text = 'Acceleration (m/s^2):          x: -0.02          y: 0.03          z: 0.01';
            app.GyroscopesLabel = uilabel(app.SensorMeasurementsPanel);
            app.GyroscopesLabel.Text = 'Gyroscopes:';
            app.ServoOutputxyzLabel = uilabel(app.SensorMeasurementsPanel);
            app.ServoOutputxyzLabel.Text = 'Servo Output (%):          x:           y:           z: ';
            app.MotorOutputxyzLabel = uilabel(app.SensorMeasurementsPanel);
            app.MotorOutputxyzLabel.Text = 'Motor Output (%):          x:           y:           z: ';
            app.HeadingIndicator = uiaeroheading(app.SensorMeasurementsPanel);
            app.xGaugeLabel = uilabel(app.SensorMeasurementsPanel);
            app.xGaugeLabel.Text = 'x';
            app.xGauge = uigauge(app.SensorMeasurementsPanel, 'semicircular');
            app.xGauge.Limits = [-15 15];
            app.yGaugeLabel = uilabel(app.SensorMeasurementsPanel);
            app.yGaugeLabel.Text = 'y';
            app.yGauge = uigauge(app.SensorMeasurementsPanel, 'semicircular');
            app.yGauge.Limits = [-15 15];
            app.zGaugeLabel = uilabel(app.SensorMeasurementsPanel);
            app.zGaugeLabel.Text = 'z';
            app.zGauge = uigauge(app.SensorMeasurementsPanel, 'semicircular');
            app.zGauge.Limits = [-15 15];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUItest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end